classdef PlateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton            matlab.ui.control.Button
        SpecialMovePanel       matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        FibonacciButton        matlab.ui.control.Button
        MovePanel              matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        ToDropDown             matlab.ui.control.DropDown
        ToDropDownLabel        matlab.ui.control.Label
        FromDropDown           matlab.ui.control.DropDown
        FromDropDownLabel      matlab.ui.control.Label
        MoveButton             matlab.ui.control.Button
        ConnectionPanel        matlab.ui.container.Panel
        DISCONNECTButton       matlab.ui.control.Button
        CONNECTButton          matlab.ui.control.Button
        COMPortEditField       matlab.ui.control.NumericEditField
        COMPortEditFieldLabel  matlab.ui.control.Label
        ResponsePanel          matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        GetStatusButton        matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        XAXISPanel             matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        GripperPanel           matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        CloseButton            matlab.ui.control.Button
        OpenButton             matlab.ui.control.Button
        ZAXISPanel             matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        RetractButton          matlab.ui.control.Button
        ExtendButton           matlab.ui.control.Button
        SetTimeDelaysPanel     matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        ResetTimeDelaysButton  matlab.ui.control.Button
        SetTimeDelaysButton    matlab.ui.control.Button
        DelayTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        Com_Port
        Robot
        Delays
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.DelayTable.Data = [60 20 30
            0 30 30
            30 0 30
            30 30 0
            30 20 60];
            app.DelayTable.RowName = {'1','2','3','4','5'};
            app.Delays = [0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            app.Com_Port = app.COMPortEditField.Value;
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            app.Robot = PlateLoader(app.Com_Port);
            app.TextArea.Value = 'READY';
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            app.Robot = PlateLoaderSim;
            app.TextArea.Value = 'READY';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            response = app.Robot.reset;
            app.TextArea.Value = response;
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            [xAxis, zAxis, gripperClosed, platePresent] = app.Robot.getProperties;
            str1 = 'X-Axis: ';
            str2 = num2str(xAxis);
            str3 = '  Z-Axis:';
            if zAxis
                str4 = ' Extended';
            else
                str4 = ' Retracted';
            end
            str5 = '  Gripper:';
            if gripperClosed
                str6 = ' Closed';
            else
                str6 = ' Open';
            end
            str7 = '  Plate:';
            if platePresent
                str8 = ' Present';
            else
                str8 = ' Not Present';
            end
            app.TextArea.Value = strcat(str1,str2,str3,str4,str5,str6,str7,str8);
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            response = app.Robot.x(1);
            app.TextArea.Value = response;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            response = app.Robot.x(2);
            app.TextArea.Value = response;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            response = app.Robot.x(3);
            app.TextArea.Value = response;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            response = app.Robot.x(4);
            app.TextArea.Value = response;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            response = app.Robot.x(5);
            app.TextArea.Value = response;
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            response = app.Robot.retract;
            app.TextArea.Value = response;
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            response = app.Robot.extend;
            app.TextArea.Value = response;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            response = app.Robot.open;
            app.TextArea.Value = response;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            response = app.Robot.close;
            app.TextArea.Value = response;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            response = app.Robot.movePlate(str2double(app.FromDropDown.Value),str2double(app.ToDropDown.Value));
            app.TextArea.Value = response;
        end

        % Button pushed function: FibonacciButton
        function FibonacciButtonPushed(app, event)
            response = app.Robot.specialMove;
            app.TextArea.Value = response;
        end

        % Button pushed function: ResetTimeDelaysButton
        function ResetTimeDelaysButtonPushed(app, event)
            response = app.Robot.resetDefaultTimes;
            app.DelayTable.Data = [60 20 30
            0 30 30
            30 0 30
            30 30 0
            30 20 60];
            app.TextArea.Value = response;
        end

        % Button pushed function: SetTimeDelaysButton
        function SetTimeDelaysButtonPushed(app, event)
            response = app.Robot.setTimeValues([zeros(5,1),app.DelayTable.Data,zeros(5,1)]);
            app.TextArea.Value = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetTimeDelaysPanel = uipanel(app.UIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.GridLayout5 = uigridlayout(app.SetTimeDelaysPanel);
            app.DelayTable = uitable(app.GridLayout5);
            app.DelayTable.ColumnName = {'2'; '3'; '4'};
            app.DelayTable.RowName = {'1,2,3,4,5'};
            app.DelayTable.ColumnEditable = true;
            app.SetTimeDelaysButton = uibutton(app.GridLayout5, 'push');
            app.SetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @SetTimeDelaysButtonPushed, true);
            app.SetTimeDelaysButton.Text = 'Set Time Delays';
            app.ResetTimeDelaysButton = uibutton(app.GridLayout5, 'push');
            app.ResetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeDelaysButtonPushed, true);
            app.ResetTimeDelaysButton.Text = 'Reset Time Delays';
            app.ZAXISPanel = uipanel(app.UIFigure);
            app.ZAXISPanel.Title = 'Z-AXIS';
            app.GridLayout3 = uigridlayout(app.ZAXISPanel);
            app.ExtendButton = uibutton(app.GridLayout3, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.RetractButton = uibutton(app.GridLayout3, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.GripperPanel = uipanel(app.UIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.GridLayout2 = uigridlayout(app.GripperPanel);
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.XAXISPanel = uipanel(app.UIFigure);
            app.XAXISPanel.Title = 'X-AXIS';
            app.GridLayout = uigridlayout(app.XAXISPanel);
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.ResponsePanel = uipanel(app.UIFigure);
            app.ResponsePanel.Title = 'Response';
            app.GridLayout7 = uigridlayout(app.ResponsePanel);
            app.TextArea = uitextarea(app.GridLayout7);
            app.GetStatusButton = uibutton(app.GridLayout7, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.COMPortEditFieldLabel = uilabel(app.ConnectionPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.CONNECTButton = uibutton(app.ConnectionPanel, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.ConnectionPanel, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.MovePanel = uipanel(app.UIFigure);
            app.MovePanel.Title = 'Move';
            app.GridLayout4 = uigridlayout(app.MovePanel);
            app.MoveButton = uibutton(app.GridLayout4, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.FromDropDownLabel = uilabel(app.GridLayout4);
            app.FromDropDownLabel.Text = 'From';
            app.FromDropDown = uidropdown(app.GridLayout4);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.ToDropDownLabel = uilabel(app.GridLayout4);
            app.ToDropDownLabel.Text = 'To';
            app.ToDropDown = uidropdown(app.GridLayout4);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.SpecialMovePanel = uipanel(app.UIFigure);
            app.SpecialMovePanel.Title = 'Special Move';
            app.GridLayout6 = uigridlayout(app.SpecialMovePanel);
            app.FibonacciButton = uibutton(app.GridLayout6, 'push');
            app.FibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @FibonacciButtonPushed, true);
            app.FibonacciButton.Text = 'Fibonacci';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end